classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LoadImageButton        matlab.ui.control.Button
        GoImpressionismButton  matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        TextArea_2             matlab.ui.control.TextArea
        ProcessedImage         matlab.ui.control.UIAxes
        OriginalImage          matlab.ui.control.UIAxes
        progressStatus         matlab.ui.control.TextArea
    end


    properties (Access = public)
        sourceImage % Description
    end


    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

            [fileName, filePath] = uigetfile('*.bmp', 'select jpg image');
            if isnumeric(fileName); return; end      %user cancelled
            app.sourceImage = imread( fullfile(filePath, fileName));
            imshow(app.sourceImage, 'parent', app.OriginalImage);
            app.progressStatus.Value = {'Original Image Loaded'};
        end

        % Button pushed function: GoImpressionismButton
        function GoImpressionismButtonPushed(app, event)
            R = [8,6,4 2];
            app.progressStatus.Value = {'Image is Processing...'};
            pause(1);
            oilPaint = paint(app.sourceImage, R);
            imshow(oilPaint, 'parent', app.ProcessedImage);
            pause(1);
            app.progressStatus.Value = {'Processing finished'};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.GoImpressionismButton = uibutton(app.UIFigure, 'push');
            app.GoImpressionismButton.ButtonPushedFcn = createCallbackFcn(app, @GoImpressionismButtonPushed, true);
            app.GoImpressionismButton.Text = 'Go Impressionism';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Original Image'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Oil Paint'};
            app.ProcessedImage = uiaxes(app.UIFigure);
            app.ProcessedImage.XTick = [];
            app.ProcessedImage.XTickLabel = {'[ ]'};
            app.ProcessedImage.YTick = [];
            app.ProcessedImage.YTickLabel = {'[ ]'};
            app.OriginalImage = uiaxes(app.UIFigure);
            app.OriginalImage.XTick = [];
            app.OriginalImage.XTickLabel = {'[ ]'};
            app.OriginalImage.YTick = [];
            app.progressStatus = uitextarea(app.UIFigure);
            app.progressStatus.Value = {'Processing Not Started'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end